classdef Schematic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabCADToolUIFigure  matlab.ui.Figure
        OptionsMenu            matlab.ui.container.Menu
        GridMenu               matlab.ui.container.Menu
        HelpMenu               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Schema % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Schema = SchematicObj();
            app.Schema.SetFigure(app.MatlabCADToolUIFigure);
            app.Schema.StartApp();
        end

        % Callback function
        function OpenSchematicButtonPushed(app, event)
            app.Schema = SchematicObj();
            app.Schema.StartApp();
        end

        % Callback function
        function CloseSchematicButtonPushed(app, event)
            close all
            app.Schema = [];
        end

        % Close request function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureCloseRequest(app, event)
            app.CloseSchematicButtonPushed([]);
            delete(app)
            
        end

        % Window button motion function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureWindowButtonMotion(app, event)
             pos = get(app.Schema.GrObj,'CurrentPoint');
            x_int = round(pos(1,1));  % x grid with delta X  = 1
            y_int = round(pos(1,2));  % y grid with delta Y  = 1
            if x_int > app.Schema.Xlim(2) ||  x_int < app.Schema.Xlim(1) 
               return %limit violation
            end
            if y_int > app.Schema.Ylim(2) || y_int < app.Schema.Ylim(1) 
                return %limit violation
            end 
            if app.Schema.Manager.selectMultiObjectFlag
               if ~isempty(app.Schema.Manager.SelectionRectangle)
                   Xo = app.Schema.Manager.SelectionRectangle.XData(1);
                   Yo = app.Schema.Manager.SelectionRectangle.YData(1);
                   [X,Y] = SchematicManager.getXYfromPos(Xo,Yo,x_int - Xo,y_int - Yo);
                   app.Schema.Manager.SelectionRectangle.XData = X;
                   app.Schema.Manager.SelectionRectangle.YData = Y;
               end 
                return
            end
            obj = app.Schema.Manager.FindAnchorsByCoordinate(x_int,y_int);
            if isempty(obj)
                % do nothing    
            else
                obj.EdgeColor = 'red';
                obj.FaceColor = 'red';
                return
            end
            [value,objList] = app.Schema.Manager.FindObjbyProperty('ObjSelected',true,true); % third flag is the option to return the List of objects which satisfy the first two entries
            if value && app.Schema.Manager.AllowComponentDragAndDrop
                if ~app.Schema.Manager.MultiSelectionFlag && ~isa(objList{1},'LineObj')
                    obj = objList{1};
                    obj.Xo = x_int;
                    obj.Yo = y_int;
                    obj.DisplayGrObj()
                    drawnow
                    return
                else
                    Xpos = app.Schema.Manager.Xpos;
                    Ypos = app.Schema.Manager.Ypos;
                    app.Schema.Manager.Xpos = x_int;
                    app.Schema.Manager.Ypos = y_int;
                    for i = 1 : numel(objList)
                        obj = objList{i};
                        if ~isa(obj,'LineObj')
                            Xo = obj.Xo;
                            Yo = obj.Yo;
                            obj.Xo =    round(x_int - Xpos + Xo);
                            obj.Yo = round(y_int - Ypos + Yo);
                            obj.DisplayGrObj()
                        end
                    end
                    return
                end               
            end
            if ~isempty(app.Schema.Manager.currentLineObj) && app.Schema.Manager.DrawConnectionFlag
              Line = app.Schema.Manager.currentLineObj;
                if ~isempty(Line.ObjA)
                    if x_int > Line.A(1)
                        x = x_int - 1.5;
                    else
                        x = x_int + 1.5;
                    end
                    if y_int > Line.A(2)
                        y = y_int - 1.5;
                    else
                        y = y_int + 1.5;
                    end
                    Line.ConnectBy2PointsCoordinates(Line.A(1),Line.A(2),x,y)
                    Line.UpdateStraightLineDisplay()
                    drawnow
                    return
                end
            end
        end

        % Window button down function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureWindowButtonDown(app, event)
            pos = get(app.Schema.GrObj,'CurrentPoint');
            x_int = round(pos(1,1));  % x grid with delta X  = 1
            y_int = round(pos(1,2));  % y grid with delta Y  = 1
            fprintf('X = %4.2f Y = %4.2f \n',x_int,y_int)
            app.MatlabCADToolUIFigure.Color = '#D95319';
            if app.Schema.Manager.selectMultiObjectFlag
                if isempty(app.Schema.Manager.SelectionRectangle)
                    pos = get(app.Schema.GrObj,'CurrentPoint');
                    x = pos(1,1);  % x grid with delta X  = 1
                    y = pos(1,2);  % y grid with delta Y  = 1
                    [X,Y] = SchematicManager.getXYfromPos(x,y,0.1,0.1);
                     app.Schema.Manager.SelectionRectangle = patch('XData',X,'YData',Y,'FaceColor','none','EdgeColor','r','LineStyle',':');
               end
            end
        end

        % Window button up function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureWindowButtonUp(app, event)
            [value,objList] = app.Schema.Manager.FindObjbyProperty('ObjSelected',true,true);
            if value
                for i = 1 : numel(objList)
                    obj = objList{i};
                    obj.ObjSelected = false;
                end 
                if app.Schema.Manager.MultiSelectionFlag
                    app.Schema.Manager.MultiSelectionFlag = false;
                end
            end    
            if app.Schema.Manager.selectMultiObjectFlag    
               if ~isempty(app.Schema.Manager.SelectionRectangle)
                   Xmax = max(app.Schema.Manager.SelectionRectangle.XData);
                   Xmin = min(app.Schema.Manager.SelectionRectangle.XData);
                   Ymax = max(app.Schema.Manager.SelectionRectangle.YData);
                   Ymin = min(app.Schema.Manager.SelectionRectangle.YData);
                   List = app.Schema.Manager.findObjByCoordianteRange(Xmin,Xmax,Ymin,Ymax);
                   if ~isempty(List)
                        for i  = 1 : numel(List)
                            List{i}.ObjSelected = true;
                        end
                   end
                   delete(app.Schema.Manager.SelectionRectangle)
                   app.Schema.Manager.SelectionRectangle = [];
               end
               app.Schema.Manager.selectMultiObjectFlag = false;
               disp('Multi Select OFF')
               app.Schema.Manager.MultiSelectionFlag = true;
               app.Schema.Fig.Pointer = 'arrow';
                pos = get(app.Schema.GrObj,'CurrentPoint');
                x = round(pos(1,1));  % x grid with delta X  = 1
                y = round(pos(1,2));  % y grid with delta Y  = 1
                app.Schema.Manager.Xpos = x;
                app.Schema.Manager.Ypos = y;
           
            end
            app.Schema.Manager.UpdateConnectionLines();
            app.MatlabCADToolUIFigure.Color = '#4DBEEE';
        end

        % Window key press function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureWindowKeyPress(app, event)
            Key = event.Key;
            global crlKeyPressed shiftKeyPressed       
            if ~exist('crlKeyPressed','var') || isempty(crlKeyPressed)
                crlKeyPressed = false;
            end
            if strcmpi(Key,'control') 
                crlKeyPressed = true;
            end
            if ~exist('shiftKeyPressed','var') || isempty(shiftKeyPressed)
                shiftKeyPressed = false;
            end
            if strcmpi(Key,'shift')
                shiftKeyPressed = true;
            end
            if crlKeyPressed && strcmpi(Key,'r')  % CTRL + R: Combiation to rotate the component
                 [value,objList] = app.Schema.Manager.FindObjbyProperty('ObjSelected',true,true);
                if value
                    for i = 1 : numel(objList)
                        obj = objList{i};
                        obj.RotateObj()
                    end
                else
                    disp('Component not found')
                end
                return
            end
            if strcmpi(Key,'delete')  % CTRL + R: Combiation to rotate the component
                [value,objList] = app.Schema.Manager.FindObjbyProperty('ObjSelected',true,true);
                if value
                    if app.Schema.Manager.MultiSelectionFlag
                        app.Schema.Manager.MultiSelectionFlag = false;
                    end
                    for i = 1 : numel(objList)
                        obj = objList{i};
                        Name = obj.Name;
                        app.Schema.Manager.RemoveSchematicObjects(Name)
                        app.Schema.Manager.UpdateConnectionLines()
                    end
                else
                    disp('Select the objet to delete')
                end
                return
            end
            if strcmpi(Key,'w')
                disp('drawing line')
                app.Schema.Manager.DrawConnectionFlag = true;
                app.Schema.Manager.InitilizeConnection();
                return
            end          
            if strcmpi(Key,'r')
                app.Schema.Manager.AddComponents('Resistor')
                disp('Added Resistor')
                return
            end            
            if strcmpi(Key,'c')
                app.Schema.Manager.AddComponents('Capacitor')
                disp('Added Capacitor')
                return
            end            
            if strcmpi(Key,'z')
                app.Schema.Manager.AddComponents('Zener')
                disp('Added Zener')
                return
            end          
            if strcmpi(Key,'d')
                app.Schema.Manager.AddComponents('Diode')
                disp('Added Diode')
                return
            end 
           if strcmpi(Key,'l')
                app.Schema.Manager.AddComponents('Inductor')
                disp('Added Inductor')
                return
           end           
           if strcmpi(Key,'q')
                app.Schema.Manager.AddComponents('Mosfet')
                disp('Added Mosfet')
                return
           end
           if strcmpi(Key,'f')
                app.Schema.Manager.AddComponents('Fuse')
                disp('Added Mosfet')
                return
           end
           if strcmpi(Key,'p')
                app.Schema.Manager.AddComponents('Pin')
                disp('Added Pin')
                return
           end
           if strcmpi(Key,'m')
                app.Schema.Manager.AddComponents('CMChoke')
                disp('Added Mosfet')
                return
           end
           
           if strcmpi(Key,'t')
               
                app.Schema.Manager.AddComponents('Transformer')
                disp('Added Transformer')
                return
           end
        end

        % Window key release function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureWindowKeyRelease(app, event)
            key = event.Key;
            global crlKeyPressed shiftKeyPressed
            if strcmpi(key,'control')
                crlKeyPressed = false;
            end
            if strcmpi(key,'shift')
                shiftKeyPressed = false;
            end
        end

        % Window scroll wheel function: MatlabCADToolUIFigure
        function MatlabCADToolUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            global crlKeyPressed
            
            if ~exist('crlKeyPressed','var') || isempty(crlKeyPressed)
                crlKeyPressed = false;
            end
            
            if ~crlKeyPressed
                return
            end
            
            
            ZoomX = round(-verticalScrollCount*10);
            ZoomY = ZoomX; %* app.Schema.Manager.XYRatio;
            
            
            XLim = app.Schema.Manager.SchematicAxes.XLim;
            YLim = app.Schema.Manager.SchematicAxes.YLim;
            
            
            NewXLim = XLim + [-ZoomX,ZoomX];
            NewYLim = YLim + [-ZoomY,ZoomY];
            
            if NewXLim(1) > NewXLim(2)
                NewXLim(1) = -1;
                NewXLim(1) = +1;
            end
            
            if NewYLim(1) > NewYLim(2)
                NewYLim(1) = NewXLim(1) * app.Schema.XYRatio;
                NewXLim(1) = NewXLim(2) * app.Schema.XYRatio;
            end
            app.Schema.Manager.SchematicAxes.XLim  = NewXLim; 
            app.Schema.Manager.SchematicAxes.YLim  = NewYLim; 
            
            
            app.Schema.Xlim = NewXLim;
            app.Schema.Ylim = NewYLim;
        end

        % Callback function
        function OkButtonPushed(app, event)
            
        end

        % Menu selected function: GridMenu
        function GridMenuSelected(app, event)
        grid(app.Schema.GrObj);
            if strcmp(event.Source.Checked,'on')
                event.Source.Checked = 'off';
            else
                event.Source.Checked = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabCADToolUIFigure = uifigure('Visible', 'off');
            app.MatlabCADToolUIFigure.Name = 'Matlab CAD Tool';
            app.MatlabCADToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatlabCADToolUIFigureCloseRequest, true);
            app.MatlabCADToolUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MatlabCADToolUIFigureWindowButtonDown, true);
            app.MatlabCADToolUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @MatlabCADToolUIFigureWindowButtonUp, true);
            app.MatlabCADToolUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MatlabCADToolUIFigureWindowButtonMotion, true);
            app.MatlabCADToolUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @MatlabCADToolUIFigureWindowScrollWheel, true);
            app.MatlabCADToolUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MatlabCADToolUIFigureWindowKeyPress, true);
            app.MatlabCADToolUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @MatlabCADToolUIFigureWindowKeyRelease, true);
            app.OptionsMenu = uimenu(app.MatlabCADToolUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.GridMenu = uimenu(app.OptionsMenu);
            app.GridMenu.MenuSelectedFcn = createCallbackFcn(app, @GridMenuSelected, true);
            app.GridMenu.Text = 'Grid';
            app.HelpMenu = uimenu(app.MatlabCADToolUIFigure);
            app.HelpMenu.Text = 'Help ';
            % Show the figure after all components are created
            app.MatlabCADToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Schematic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabCADToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabCADToolUIFigure)
        end
    end
end